classdef LEGENDA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Programa               matlab.ui.Figure
        LegendaEditFieldLabel  matlab.ui.control.Label
        Legenda                matlab.ui.control.EditField
        EmqualfiguraalegendaserinseridaLabel  matlab.ui.control.Label
        Figura                 matlab.ui.control.DropDown
        Exemplo                matlab.ui.control.Label
        OKButton               matlab.ui.control.Button
        CancelarButton         matlab.ui.control.Button
    end


    properties (Access = private)
        Property % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Property.fig = 0;
            app.Property.legenda = '';
        end

        % Button pushed function: CancelarButton
        function CancelarButtonPushed(app, event)
            close(app.Programa);
        end

        % Value changed function: Figura
        function FiguraValueChanged(app, event)
            app.Property.fig = app.Figura.Value;
        end

        % Value changed function: Legenda
        function LegendaValueChanged(app, event)
            leg = string(app.Legenda.Value);
            leg = strsplit(leg, ',');
            leg = strtrim(leg);
            app.Property.legenda = leg;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            fig = app.Property.fig;
            if app.Property.legenda ~= ''
                if fig == '1'
                    figure(1)
                    legend(app.Property.legenda);
                    closereq
                elseif fig == '2'
                    figure(2)
                    legend(app.Property.legenda);
                    closereq
                elseif fig == '3'
                    figure(3)
                    legend(app.Property.legenda);
                    closereq
                elseif fig == '4'
                    figure(4)
                    legend(app.Property.legenda);
                    closereq
                elseif fig == '5'
                    figure(5)
                    legend(app.Property.legenda);
                    closereq
                elseif fig == '6'
                    figure(6)
                    legend(app.Property.legenda);
                    closereq
                elseif fig == '7'
                    figure(7)
                    legend(app.Property.legenda);
                    closereq
                elseif fig == '8'
                    figure(8)
                    legend(app.Property.legenda);
                    closereq
                elseif fig == '9'
                    figure(9)
                    legend(app.Property.legenda);
                    closereq
                elseif fig == '10'
                    figure(10)
                    legend(app.Property.legenda);
                    closereq
                elseif fig == '11'
                    figure(11)
                    legend(app.Property.legenda);
                    closereq
                elseif fig == '12'
                    figure(12)
                    legend(app.Property.legenda);
                    closereq
                else
                    warndlg('Selecione um gráfico para inserir legenda!')
                end
            else
                warndlg('Informe a legenda que deseja adicionar!')
            end
        end

        % Close request function: Programa
        function ProgramaCloseRequest(app, event)
            canc = questdlg('Tem certeza que deseja cancelar?', 'Cancelar', 'Sim','Não', 'Não');
            if strcmp(canc,'Sim') == 1
                closereq
            end           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Programa = uifigure;
            app.Programa.Name = 'Legenda';
            app.Programa.CloseRequestFcn = createCallbackFcn(app, @ProgramaCloseRequest, true);
            app.LegendaEditFieldLabel = uilabel(app.Programa);
            app.LegendaEditFieldLabel.Text = 'Legenda';
            app.Legenda = uieditfield(app.Programa, 'text');
            app.Legenda.ValueChangedFcn = createCallbackFcn(app, @LegendaValueChanged, true);
            app.EmqualfiguraalegendaserinseridaLabel = uilabel(app.Programa);
            app.EmqualfiguraalegendaserinseridaLabel.Text = 'Em qual figura a legenda será inserida?';
            app.Figura = uidropdown(app.Programa);
            app.Figura.Items = {'Selecione', '1 - Tensão De Entrada', '2 - Corrente De Estator', '3 - Fluxo Magnético', '4 - Torque Eletromagnético', '5 - Torque De Carga', '6 - Torque x Velocidade', '7 - Velocidade', '8 - Corrente De Estator (Frequência)', '9 - Tensão De Entrada (Frequência)', '10 - Potência Ativa Inst. De Entrada', '11 - Potência Ativa Média De Entrada', '12 - Potência Ativa De Saída'};
            app.Figura.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.Figura.ValueChangedFcn = createCallbackFcn(app, @FiguraValueChanged, true);
            app.Figura.Value = '0';
            app.Exemplo = uilabel(app.Programa);
            app.Exemplo.Text = 'Exemplo: Legenda 1, Legenda 2, Legenda 3...';
            app.OKButton = uibutton(app.Programa, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelarButton = uibutton(app.Programa, 'push');
            app.CancelarButton.ButtonPushedFcn = createCallbackFcn(app, @CancelarButtonPushed, true);
            app.CancelarButton.Text = 'Cancelar';
        end
    end

    methods (Access = public)

        % Construct app
        function app = LEGENDA

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Programa)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Programa)
        end
    end
end