classdef MODELOMIT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Programa              matlab.ui.Figure
        motor                 matlab.ui.container.ButtonGroup
        op1                   matlab.ui.control.RadioButton
        op2                   matlab.ui.control.RadioButton
        dadosmotor            matlab.ui.control.TextArea
        op3                   matlab.ui.control.RadioButton
        acionamento           matlab.ui.container.ButtonGroup
        senoidal              matlab.ui.control.RadioButton
        pwm                   matlab.ui.control.RadioButton
        svpwm                 matlab.ui.control.RadioButton
        simular               matlab.ui.control.Button
        salvar                matlab.ui.control.Button
        sair                  matlab.ui.control.Button
        carregar              matlab.ui.control.Button
        grafico               matlab.ui.container.ButtonGroup
        def                   matlab.ui.control.ToggleButton
        g2                    matlab.ui.control.ToggleButton
        g3                    matlab.ui.control.ToggleButton
        g4                    matlab.ui.control.ToggleButton
        g5                    matlab.ui.control.ToggleButton
        g6                    matlab.ui.control.ToggleButton
        g7                    matlab.ui.control.ToggleButton
        g8                    matlab.ui.control.ToggleButton
        g9                    matlab.ui.control.ToggleButton
        g10                   matlab.ui.control.ToggleButton
        g11                   matlab.ui.control.ToggleButton
        g12                   matlab.ui.control.ToggleButton
        g1                    matlab.ui.control.ToggleButton
        Legenda               matlab.ui.control.Button
        limpar                matlab.ui.control.Button
        status                matlab.ui.control.TextArea
        carga                 matlab.ui.container.ButtonGroup
        cantes                matlab.ui.control.RadioButton
        capos                 matlab.ui.control.RadioButton
        cgrad                 matlab.ui.control.RadioButton
        porcar                matlab.ui.control.NumericEditField
        texto1                matlab.ui.control.Label
        texto2                matlab.ui.control.Label
        texto3                matlab.ui.control.Label
        ajudamotor            matlab.ui.control.Button
        ajudacarga            matlab.ui.control.Button
        ajudaacion            matlab.ui.control.Button
        ajudagraf             matlab.ui.control.Button
        ajudaopsim            matlab.ui.control.Button
        opsimu                matlab.ui.container.ButtonGroup
        tmax                  matlab.ui.control.NumericEditField
        PassodaSimulaosLabel  matlab.ui.control.Label
        passo                 matlab.ui.control.NumericEditField
        TempodaSimulaosLabel  matlab.ui.control.Label
        workspace             matlab.ui.control.TextArea
        info                  matlab.ui.control.Button
        lampgrafico           matlab.ui.control.Lamp
    end


    properties (Access = private)
        Property % Description
        Parmot
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.Property.parametros = [220,60,0.435,0.754,11.9,26.13,0.089,0.816,0.754,4];
            app.dadosmotor.Value = {'DADOS NOMINAIS DO MOTOR', 'Tesão ..................... 220 V', 'Frequência ............. 60 Hz', 'Torque ................... 11,9 N.m', 'Núm. de polos ........ 4', 'Res. do estator ....... 0,435 Ohm', 'Res. do rotor ........... 0,816 Ohm', 'Mom. de inércia ....... 0,089 Kg.m²', 'Ind. mútua .............. 26,13 Ohm', 'Ind. de disp.', 'do estator ............... 0,754 Ohm', 'Ind. de disp.', 'do rotor ................... 0,754 Ohm'  }; 
            app.Property.m = 1;
            app.Property.c = 1;
            app.Property.acion = 1;
            app.Property.si = 0;
            app.Property.leg_m = "mit1";
            app.Property.leg_a = "-sen";
            app.Property.leg_c = "-cra";
            app.Property.wrk1 = "Motor 1";
            app.Property.wrk2 = "Senoidal";
            app.Property.tm = 1;
            app.Property.ps = 0.00001;
            app.lampgrafico.Color = 'red';
            app.status.Value = ' ';
            app.workspace.Value = ' ';
            app.Property.carga = 1;
            app.Property.tcarga = 100;
            app.Property.legenda = [];
        end

        % Button pushed function: Legenda
        function LegendaButtonPushed(app, event)
            LEGENDA
        end

        % Close request function: Programa
        function ProgramaCloseRequest(app, event)
             if app.Property.c == 1 
                canc = questdlg('Tem certeza que deseja sair?', 'Sair','Sim', 'Não', 'Não');
                 if strcmp(canc,'Sim') == 1
                     closereq
                 end    
             else
                 warndlg('Feche a aba "Novo Motor" primeiro!')
             end                 
        end

        % Selection changed function: acionamento
        function acionamentoSelectionChanged(app, event)
            if app.senoidal.Value == 1
                app.Property.acion = 1;
                leg_a = "-sen";
                app.Property.wrk2 = "Senoidal";
            elseif app.pwm.Value == 1
                app.Property.acion = 2;
                leg_a = "-pwm";
                app.Property.wrk2 = "Inversor PWM";
            elseif app.svpwm.Value == 1
                app.Property.acion = 3;
                leg_a = "-svpwm";
                app.Property.wrk2 = "Inversor SVPWM";
            end
            app.Property.leg_a = leg_a;
        end

        % Callback function
        function ajuda1ButtonPushed(app, event)
            helpdlg('A carga será adicionada da seguinte forma: metade será inserida no primeiro terço de simulação e a outra metade, no segundo terço de simulação.');
        end

        % Button pushed function: ajudaacion
        function ajudaacionButtonPushed(app, event)
            helpdlg(sprintf('Nesta seção você poderá escolher como deseja acionar o seu motor:\n   1) Através de tensões trifásicas puramente senoidais.\n   2) Através de um inversor de frequência com modulação PWM (Pulse Width \n   Modulation).\n   3) Através de um inversor de frequência com modulação SVPWM (Space\n   Vector Pulse Width Modulation).\n\nobs: Os inversores de frequêcia estão configurados em uma frequência de chaveamento de 2500 Hz.'));
        end

        % Button pushed function: ajudacarga
        function ajudacargaButtonPushed(app, event)
            helpdlg(sprintf('Nesta seção você poderá escolher os dados da carga que será inserida no seu motor:\n   - Em "TORQUE DA CARGA", escolha a porcentagem da carga nominal que\n   deseja inserir no motor.\n   - Na caixa de seleção, escolha uma das três opções:\n      1) Inserir carga antes da partida\n      2) Inserir carga na metade da simulação\n      3) Inserir metade da carga no 1° terço da simulação e a outra\n      metade, no 2° terço da simulação'));
        end

        % Button pushed function: ajudagraf
        function ajudagrafButtonPushed(app, event)
            helpdlg(sprintf('Nesta seção você poderá plotar diferentes gráficos referentes a uma simulação ou, a um arquivo de dados carregado.\n\nobs: Esta seção só estará disponível quando o programa possuir dados (simulados ou carregados) e a luz verde ao lado do título estiver verde.'));
        end

        % Button pushed function: ajudamotor
        function ajudamotorButtonPushed(app, event)
             helpdlg('Nesta seção você poderá escolher entre dois motores pré-definidos ou, poderá também, inserir um novo motor de acordo com as suas preferências.');
        end

        % Button pushed function: ajudaopsim
        function ajudaopsimButtonPushed(app, event)
            helpdlg(sprintf('Nesta seção você poderá escolher o tempo máximo e o passo (intervalo mínimo de tempo entre dois pontos de aquisição de sinal) da simulação'));
        end

        % Selection changed function: carga
        function cargaSelectionChanged(app, event)
            if app.cantes.Value == 1
                app.Property.leg_c = "-cra";
                app.Property.carga = 1;
            elseif app.capos.Value == 1
                app.Property.leg_c = "-crd";
                app.Property.carga = 2;
            elseif app.cgrad.Value == 1
                app.Property.leg_c = "-crg";
                app.Property.carga = 3;
            end
            
        end

        % Button pushed function: carregar
        function carregarButtonPushed(app, event)
            app.workspace.Value = '';
            file = uigetfile('*.csv');
            app.status.Value = ' Carregando...';
            if file ~= 0
                dados = csvread(file, 2);
                vabc = dados(:,1:3);
                iabcs = dados(:,4:6);
                iabcr = dados(:,7:9);  
                fabcs = dados(:,10:12);
                fabcr = dados(:,13:15);
                tempo = dados(:,16);
                torque = dados(:,17);
                torquecarga = dados(:,18);
                rot = dados(:,19);
                psaida = dados(:,20);
                pmedioentrada = dados(:,21);
                pinstentrada = dados(:,22);
                dadosm = csvread(file,0,0,[0 0 0 15]);
                app.Property.dados = dados;
                app.Property.vabc = vabc;
                app.Property.iabcs = iabcs;
                app.Property.iabcr = iabcr;
                app.Property.fabcs = fabcs;
                app.Property.fabcr = fabcr;
                app.Property.tempo = tempo;
                app.Property.torque = torque;
                app.Property.torquecarga = torquecarga;
                app.Property.rot = rot;
                app.Property.psaida = psaida;
                app.Property.pmedioentrada = pmedioentrada;
                app.Property.pinstentrada = pinstentrada;
                app.Property.si = 2;
                app.lampgrafico.Color = [0 0.8 0];
                app.status.Value = ' O arquivo foi carregado com sucesso!';
                app.workspace.Value = sprintf('Arquivo carregado\n\nNome do arquivo: %s\nNúmero do dados: %g\nTempo de simulação: %g s\nTesão: %g V\nFrequência: %g Hz\nTorque: %g N.m\nNúm. de polos: %g\nRes. do estator: %g Ohm\nRes. do rotor: %g Ohm\nMom. de inércia: %g Kg.m²\nInd. mútua: %g Ohm\nInd. de disp. do estator: %g Ohm\nInd. de disp. do rotor: %g Ohm', file, length(dados), dadosm(11), dadosm(1), dadosm(2), dadosm(5), dadosm(10), dadosm(3), dadosm(8), dadosm(7),  dadosm(6), dadosm(4), dadosm(9)); 
            end
        end

        % Selection changed function: grafico
        function graficoSelectionChanged(app, event)
            si = app.Property.si;
            if si == 0
                warndlg('Você não possui nenhum dado de simulação carregado.')
            else
                vabc = app.Property.vabc;
                iabcs = app.Property.iabcs;
                iabcr = app.Property.iabcr;
                fabcs = app.Property.fabcs;
                fabcr = app.Property.fabcr;
                tempo = app.Property.tempo;
                torque = app.Property.torque;
                torquecarga = app.Property.torquecarga;
                rot = app.Property.rot;
                psaida = app.Property.psaida;
                pmedioentrada = app.Property.pmedioentrada;
                pinstentrada = app.Property.pinstentrada;
                if app.g1.Value  == 1
                    graficoapp(vabc, iabcs, iabcr, fabcs, fabcr, tempo, torque, torquecarga, rot, psaida, pmedioentrada, pinstentrada, 1);
                elseif app.g2.Value == 1
                    graficoapp(vabc, iabcs, iabcr, fabcs, fabcr, tempo, torque, torquecarga, rot, psaida, pmedioentrada, pinstentrada, 2);
                elseif app.g3.Value  == 1
                    graficoapp(vabc, iabcs, iabcr, fabcs, fabcr, tempo, torque, torquecarga, rot, psaida, pmedioentrada, pinstentrada, 3);
                elseif app.g4.Value  == 1
                    graficoapp(vabc, iabcs, iabcr, fabcs, fabcr, tempo, torque, torquecarga, rot, psaida, pmedioentrada, pinstentrada, 4);
                elseif app.g5.Value  == 1
                    graficoapp(vabc, iabcs, iabcr, fabcs, fabcr, tempo, torque, torquecarga, rot, psaida, pmedioentrada, pinstentrada, 5);
                elseif app.g6.Value  == 1
                    graficoapp(vabc, iabcs, iabcr, fabcs, fabcr, tempo, torque, torquecarga, rot, psaida, pmedioentrada, pinstentrada, 6);
                elseif app.g7.Value  == 1
                    graficoapp(vabc, iabcs, iabcr, fabcs, fabcr, tempo, torque, torquecarga, rot, psaida, pmedioentrada, pinstentrada, 7);
                elseif app.g8.Value  == 1
                    graficoapp(vabc, iabcs, iabcr, fabcs, fabcr, tempo, torque, torquecarga, rot, psaida, pmedioentrada, pinstentrada, 8);
                elseif app.g9.Value  == 1
                    graficoapp(vabc, iabcs, iabcr, fabcs, fabcr, tempo, torque, torquecarga, rot, psaida, pmedioentrada, pinstentrada, 9);
                elseif app.g10.Value  == 1
                    graficoapp(vabc, iabcs, iabcr, fabcs, fabcr, tempo, torque, torquecarga, rot, psaida, pmedioentrada, pinstentrada, 10);
                elseif app.g11.Value  == 1
                    graficoapp(vabc, iabcs, iabcr, fabcs, fabcr, tempo, torque, torquecarga, rot, psaida, pmedioentrada, pinstentrada, 11);
                elseif app.g12.Value  == 1
                    graficoapp(vabc, iabcs, iabcr, fabcs, fabcr, tempo, torque, torquecarga, rot, psaida, pmedioentrada, pinstentrada, 12);
                end
                app.def.Value = true;
            end
        end

        % Button pushed function: info
        function infoButtonPushed(app, event)
            msgbox(sprintf('MODELO DINÂMICO DO MOTOR DE INDUÇÃO TRIFÁSICO   versão 1.0.0\nUniversidade Federal De São João Del Rei\nEngenharia Elétrica\nJoão Pedro Carvalho Moreira (mcjpedro@gmail.com)\n\nCom aopoio da FAPEMIG, este programa foi desenvolvido como parte da iniciação científica coordenada pela professora Lane Maria Rabelo Baccarini (rabelo@ufsj.edu.br).'), 'Informações do programa');
        end

        % Button pushed function: limpar
        function limparButtonPushed(app, event)
            limp = questdlg('Tem certeza que deseja resetar as configurações do aplicativo?', 'Reset', 'Sim','Não', 'Não');
            if strcmp(limp, 'Sim') == 1
                startupFcn(app)
            end          
        end

        % Selection changed function: motor
        function motorSelectionChanged(app, event)
            if app.op1.Value == 1
                app.Property.leg_m = "mit1";
                app.Property.wrk1 = "Motor 1";
                app.Property.parametros = [220,60,0.435,0.754,11.9,26.13,0.089,0.816,0.754,4];
                app.Property.m = 1; 
                app.dadosmotor.Value = {'DADOS NOMINAIS DO MOTOR', 'Tesão ..................... 220 V', 'Frequência ............. 60 Hz', 'Torque ................... 11,9 N.m', 'Núm. de polos ........ 4', 'Res. do estator ....... 0,435 Ohm', 'Res. do rotor ........... 0,816 Ohm', 'Mom. de inércia ....... 0,089 Kg.m²', 'Ind. mútua .............. 26,13 Ohm', 'Ind. de disp.', 'do estator ............... 0,754 Ohm', 'Ind. de disp.', 'do rotor ................... 0,754 Ohm'  }; 
            elseif app.op2.Value == 1
                app.Property.leg_m = "mit2";
                app.Property.wrk1 = "Motor 2";
                app.Property.parametros = [2300,60,0.262,1.206,1980,54.02,11.06,0.187,1.206,4];
                app.Property.m = 1;
                app.dadosmotor.Value = {'DADOS NOMINAIS DO MOTOR', 'Tesão ..................... 2300 V', 'Frequência ............. 60 Hz', 'Torque ................... 1980 N.m', 'Núm. de polos ........ 4', 'Res. do estator ....... 0,262 Ohm', 'Res. do rotor ........... 0,187 Ohm', 'Mom. de inércia ....... 11,06 Kg.m²', 'Ind. mútua .............. 54,02 Ohm', 'Ind. de disp.', 'do estator ............... 1,206 Ohm', 'Ind. de disp.', 'do rotor ................... 1,206 Ohm'  }; 
            elseif app.op3.Value == 1
                app.Property.c = 0;
                app.Parmot = NOVOMOTOR;
                app.Parmot.initfcn(app.Programa);  
                uiwait(app.Programa);
                try
                    parametros = app.Parmot.Retorno;
                    m = app.Parmot.Motor;
                    c = app.Parmot.Close;
                    delete(app.Parmot);
                    app.Parmot = [];
                    app.Property.parametros = parametros;
                    app.Property.m = m;
                    app.Property.c = 1;
                    app.Property.leg_m = "mitu";
                    app.Property.wrk1 = "Motor do usuário";
                catch
                end
                if app.Property.m ~= 0
                    app.dadosmotor.Value = sprintf('DADOS NOMINAIS DO MOTOR \nTesão ..................... %g V\nFrequência ............. %g Hz\nTorque ................... %g N.m\nNúm. de polos ........ %g\nRes. do estator ....... %g Ohm\nRes. do rotor ........... %g Ohm\nMom. de inércia ....... %g Kg.m²\nInd. mútua .............. %g Ohm\nInd. de disp.\ndo estator ............... %g Ohm\nInd. de disp.\ndo rotor ................... %g Ohm', parametros(1), parametros(2), parametros(5), parametros(10), parametros(3), parametros(8), parametros(7),  parametros(6), parametros(4), parametros(9)); 
                else
                    app.dadosmotor.Value = '';
                end
           end
        end

        % Value changed function: passo
        function passoValueChanged(app, event)
            app.Property.ps = app.passo.Value;
        end

        % Value changed function: porcar
        function porcarValueChanged(app, event)
            app.Property.tcarga = app.porcar.Value;
        end

        % Button pushed function: sair
        function sairButtonPushed(app, event)
            close(app.Programa);
        end

        % Button pushed function: salvar
        function salvarButtonPushed(app, event)
            si = app.Property.si;
            if si == 0
                warndlg('Você não possui nenhum dado de simulação carregado!')
            else
                app.status.Value = ' Salvando...';
                dados = app.Property.dados;
                parametrossalvar = app.Property.parametros;
                parametrossalvar = [parametrossalvar app.Property.tsim zeros(1,11)];
                dados = [parametrossalvar;dados];
                nome = inputdlg('Digite o nome do arquivo');
                nome = string(nome);
                if nome ~= ''
                    nome = nome + ".csv";
                    csvwrite(nome, dados);
                    app.status.Value = ' O arquivo foi salvo com sucesso!';
                else
                    app.status.Value = '';
                    warndlg('Não foi possível salvar!')
                end
            end
        end

        % Button pushed function: simular
        function simularButtonPushed(app, event)
            if app.Property.m == 0
                warndlg('Você não possui nenhum motor selecionado!')
            else
                app.workspace.Value = '';
                app.simular.Text = 'Simulando';
                app.simular.BackgroundColor = [0 0.8 0];
                app.status.Value = ' Simulando...';
                parametros = app.Property.parametros;
                a = app.Property.acion;
                tm = app.Property.tm;
                ps = app.Property.ps;
                cr = app.Property.carga;
                pc = app.Property.tcarga;
                parametros = [parametros, a, tm, ps, cr, pc];
                [vabc, iabcs, iabcr, fabcs, fabcr, tempo, torque, torquecarga, rot, psaida, pmedioentrada, pinstentrada, tsim] = modeloapp(parametros);
                dados = [vabc, iabcs, iabcr, fabcs, fabcr, tempo, torque, torquecarga, rot, psaida, pmedioentrada, pinstentrada];
                app.Property.dados = dados;
                app.Property.vabc = vabc;
                app.Property.iabcs = iabcs;
                app.Property.iabcr = iabcr;
                app.Property.fabcs = fabcs;
                app.Property.fabcr = fabcr;
                app.Property.tempo = tempo;
                app.Property.torque = torque;
                app.Property.torquecarga = torquecarga;
                app.Property.rot = rot;
                app.Property.psaida = psaida;
                app.Property.pmedioentrada = pmedioentrada;
                app.Property.pinstentrada = pinstentrada;
                app.Property.tsim = tsim;
                app.Property.leg = app.Property.leg_m + app.Property.leg_a + app.Property.leg_c;
                app.Property.si = 1;
                app.lampgrafico.Color = [0 0.8 0];
                app.simular.Text = 'Simular';
                app.simular.BackgroundColor = [0.6 0.7 0.8];
                app.status.Value = ' Simulação concluída com sucesso!';
                app.workspace.Value = sprintf('Simulação concluida\n\nNome da simulação: %s\nTempo de simulação %g s\nNúmero de dados: %g\nMotor simulado: %s\nAcionamento do motor: %s', app.Property.leg, tsim, tm/ps, app.Property.wrk1, app.Property.wrk2);
            end
        end

        % Value changed function: tmax
        function tmaxValueChanged(app, event)
            app.Property.tm = app.tmax.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Programa = uifigure;
            app.Programa.Name = 'SimulaMIT';
            app.Programa.CloseRequestFcn = createCallbackFcn(app, @ProgramaCloseRequest, true);
            app.motor = uibuttongroup(app.Programa);
            app.motor.SelectionChangedFcn = createCallbackFcn(app, @motorSelectionChanged, true);
            app.motor.Title = 'SELECIONE O MOTOR';
            app.op1 = uiradiobutton(app.motor);
            app.op1.Text = ' MOTOR 1';
            app.op1.Value = true;
            app.op2 = uiradiobutton(app.motor);
            app.op2.Text = ' MOTOR 2';
            app.dadosmotor = uitextarea(app.motor);
            app.dadosmotor.Editable = 'off';
            app.op3 = uiradiobutton(app.motor);
            app.op3.Text = ' INSERIR NOVO MOTOR';
            app.acionamento = uibuttongroup(app.Programa);
            app.acionamento.SelectionChangedFcn = createCallbackFcn(app, @acionamentoSelectionChanged, true);
            app.acionamento.Title = 'SELECIONE O ACIONAMENTO';
            app.senoidal = uiradiobutton(app.acionamento);
            app.senoidal.Text = ' SENOIDAL';
            app.senoidal.Value = true;
            app.pwm = uiradiobutton(app.acionamento);
            app.pwm.Text = ' INVERSOR PWM';
            app.svpwm = uiradiobutton(app.acionamento);
            app.svpwm.Text = ' INVERSOR SVPWM';
            app.simular = uibutton(app.Programa, 'push');
            app.simular.ButtonPushedFcn = createCallbackFcn(app, @simularButtonPushed, true);
            app.simular.Text = 'SIMULAR';
            app.salvar = uibutton(app.Programa, 'push');
            app.salvar.ButtonPushedFcn = createCallbackFcn(app, @salvarButtonPushed, true);
            app.salvar.Text = 'Salvar';
            app.sair = uibutton(app.Programa, 'push');
            app.sair.ButtonPushedFcn = createCallbackFcn(app, @sairButtonPushed, true);
            app.sair.Text = 'Sair';
            app.carregar = uibutton(app.Programa, 'push');
            app.carregar.ButtonPushedFcn = createCallbackFcn(app, @carregarButtonPushed, true);
            app.carregar.Text = 'Carregar';
            app.grafico = uibuttongroup(app.Programa);
            app.grafico.SelectionChangedFcn = createCallbackFcn(app, @graficoSelectionChanged, true);
            app.grafico.Title = 'GRÁFICOS';
            app.def = uitogglebutton(app.grafico);
            app.def.Text = '.';
            app.def.Value = true;
            app.g2 = uitogglebutton(app.grafico);
            app.g2.Text = 'Corrente De Estator';
            app.g3 = uitogglebutton(app.grafico);
            app.g3.Text = 'Fluxo Magnético';
            app.g4 = uitogglebutton(app.grafico);
            app.g4.Text = 'Torque Eletromagnético';
            app.g5 = uitogglebutton(app.grafico);
            app.g5.Text = 'Torque De Carga';
            app.g6 = uitogglebutton(app.grafico);
            app.g6.Text = 'Torque x Velocidade';
            app.g7 = uitogglebutton(app.grafico);
            app.g7.Text = 'Velocidade';
            app.g8 = uitogglebutton(app.grafico);
            app.g8.Text = 'Corrente De Estator (Frequência)';
            app.g9 = uitogglebutton(app.grafico);
            app.g9.Text = 'Tensão De Entrada (Frequência)';
            app.g10 = uitogglebutton(app.grafico);
            app.g10.Text = 'Potência Ativa Instantânea De Entrada';
            app.g11 = uitogglebutton(app.grafico);
            app.g11.Text = 'Potência Ativa Média De Entrada';
            app.g12 = uitogglebutton(app.grafico);
            app.g12.Text = 'Potência Ativa De Saída';
            app.g1 = uitogglebutton(app.grafico);
            app.g1.Text = 'Tensão De Entrada';
            app.Legenda = uibutton(app.grafico, 'push');
            app.Legenda.ButtonPushedFcn = createCallbackFcn(app, @LegendaButtonPushed, true);
            app.Legenda.Text = 'Inserir Legenda';
            app.limpar = uibutton(app.Programa, 'push');
            app.limpar.ButtonPushedFcn = createCallbackFcn(app, @limparButtonPushed, true);
            app.limpar.Text = 'Limpar';
            app.status = uitextarea(app.Programa);
            app.status.Editable = 'off';
            app.status.Enable = 'off';
            app.carga = uibuttongroup(app.Programa);
            app.carga.SelectionChangedFcn = createCallbackFcn(app, @cargaSelectionChanged, true);
            app.carga.Title = 'SELECIONE A CARGA';
            app.cantes = uiradiobutton(app.carga);
            app.cantes.Text = ' INSERIR ANTES DA PARTIDA';
            app.cantes.Value = true;
            app.capos = uiradiobutton(app.carga);
            app.capos.Text = ' INSERIR APÓS A PARTIDA';
            app.cgrad = uiradiobutton(app.carga);
            app.cgrad.Text = ' INSERIR GRADATIVAMENTE';
            app.porcar = uieditfield(app.carga, 'numeric');
            app.porcar.ValueChangedFcn = createCallbackFcn(app, @porcarValueChanged, true);
            app.porcar.Limits = [0 Inf];
            app.porcar.Value = 100;
            app.texto1 = uilabel(app.carga);
            app.texto1.Text = 'TORQUE DA';
            app.texto2 = uilabel(app.carga);
            app.texto2.Text = 'CARGA';
            app.texto3 = uilabel(app.carga);
            app.texto3.Text = ' %';
            app.ajudamotor = uibutton(app.Programa, 'push');
            app.ajudamotor.ButtonPushedFcn = createCallbackFcn(app, @ajudamotorButtonPushed, true);
            app.ajudamotor.Icon = 'help.png';
            app.ajudamotor.Text = '';
            app.ajudacarga = uibutton(app.Programa, 'push');
            app.ajudacarga.ButtonPushedFcn = createCallbackFcn(app, @ajudacargaButtonPushed, true);
            app.ajudacarga.Icon = 'help.png';
            app.ajudacarga.Text = '';
            app.ajudaacion = uibutton(app.Programa, 'push');
            app.ajudaacion.ButtonPushedFcn = createCallbackFcn(app, @ajudaacionButtonPushed, true);
            app.ajudaacion.Icon = 'help.png';
            app.ajudaacion.Text = '';
            app.ajudagraf = uibutton(app.Programa, 'push');
            app.ajudagraf.ButtonPushedFcn = createCallbackFcn(app, @ajudagrafButtonPushed, true);
            app.ajudagraf.Icon = 'help.png';
            app.ajudagraf.Text = '';
            app.ajudaopsim = uibutton(app.Programa, 'push');
            app.ajudaopsim.ButtonPushedFcn = createCallbackFcn(app, @ajudaopsimButtonPushed, true);
            app.ajudaopsim.Icon = 'help.png';
            app.ajudaopsim.Text = '';
            app.opsimu = uibuttongroup(app.Programa);
            app.opsimu.Title = 'OPÇÕES DE SIMULAÇÃO';
            app.tmax = uieditfield(app.opsimu, 'numeric');
            app.tmax.ValueChangedFcn = createCallbackFcn(app, @tmaxValueChanged, true);
            app.tmax.Limits = [0 10];
            app.tmax.Value = 1;
            app.PassodaSimulaosLabel = uilabel(app.opsimu);
            app.PassodaSimulaosLabel.Text = 'Passo da Simulação [s]';
            app.passo = uieditfield(app.opsimu, 'numeric');
            app.passo.ValueChangedFcn = createCallbackFcn(app, @passoValueChanged, true);
            app.passo.Limits = [1e-08 1];
            app.passo.Value = 1e-05;
            app.TempodaSimulaosLabel = uilabel(app.opsimu);
            app.TempodaSimulaosLabel.Text = 'Tempo da Simulação [s]';
            app.workspace = uitextarea(app.Programa);
            app.workspace.Editable = 'off';
            app.info = uibutton(app.Programa, 'push');
            app.info.ButtonPushedFcn = createCallbackFcn(app, @infoButtonPushed, true);
            app.info.Icon = 'info.png';
            app.info.Text = '';
            app.lampgrafico = uilamp(app.Programa);
        end
    end

    methods (Access = public)

        % Construct app
        function app = MODELOMIT

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Programa)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Programa)
        end
    end
end