classdef NOVOMOTOR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Programa                        matlab.ui.Figure
        ResistnciadoEstatorEditFieldLabel  matlab.ui.control.Label
        resest                          matlab.ui.control.NumericEditField
        impest                          matlab.ui.control.NumericEditField
        texto1                          matlab.ui.control.Label
        texto2                          matlab.ui.control.Label
        FrequnciaNominalEditFieldLabel  matlab.ui.control.Label
        freque                          matlab.ui.control.NumericEditField
        TensoNominalEditFieldLabel      matlab.ui.control.Label
        tensao                          matlab.ui.control.NumericEditField
        improt                          matlab.ui.control.NumericEditField
        texto3                          matlab.ui.control.Label
        texto4                          matlab.ui.control.Label
        ResistnciadoRotorEditFieldLabel  matlab.ui.control.Label
        resrot                          matlab.ui.control.NumericEditField
        MomentoDeInrciaEditFieldLabel   matlab.ui.control.Label
        momine                          matlab.ui.control.NumericEditField
        impmut                          matlab.ui.control.NumericEditField
        texto5                          matlab.ui.control.Label
        texto6                          matlab.ui.control.Label
        VLabel                          matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        OhmLabel1                       matlab.ui.control.Label
        OhmLabel2                       matlab.ui.control.Label
        OhmLabel3                       matlab.ui.control.Label
        KgmLabel                        matlab.ui.control.Label
        OhmLabel4                       matlab.ui.control.Label
        OhmLabel5                       matlab.ui.control.Label
        cancelar                        matlab.ui.control.Button
        pronto                          matlab.ui.control.Button
        lamp1                           matlab.ui.control.Lamp
        lamp3                           matlab.ui.control.Lamp
        lamp4                           matlab.ui.control.Lamp
        lamp5                           matlab.ui.control.Lamp
        lamp7                           matlab.ui.control.Lamp
        lamp8                           matlab.ui.control.Lamp
        lamp9                           matlab.ui.control.Lamp
        lamp10                          matlab.ui.control.Lamp
        NmLabel                         matlab.ui.control.Label
        TorqueNominalLabel              matlab.ui.control.Label
        torque                          matlab.ui.control.NumericEditField
        NmeroDePolosLabel               matlab.ui.control.Label
        polos                           matlab.ui.control.NumericEditField
        lamp11                          matlab.ui.control.Lamp
        lamp12                          matlab.ui.control.Lamp
        TextArea                        matlab.ui.control.TextArea
    end


    properties (Access = private)
        Property              % Propriedades internas
        parentFigureHandle    % Programa principal
    end

    properties (Access = public)
        Retorno               % Saída para o programa principal
        Motor                 % Saída para o programa principal 
        Close                 % Saída para o programa principal
    end
    
    methods (Access = public)
        
        % COnexão entre programa principal e caixa de diálogo
        function initfcn(app,handle2parentfigure)
            app.parentFigureHandle = handle2parentfigure;   
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.Programa.Position = [100 100 736.5 526];
            app.Programa.Resize = 'off';  % Travando o redimensionamento da janela
            app.Property.ten = 220;       % Valor padrão
            app.Property.fre = 60;        % Valor padrão
            app.Property.res = 0.435;     % Valor padrão
            app.Property.ies = 0.754;     % Valor padrão
            app.Property.imu = 26.13;     % Valor padrão
            app.Property.min = 0.089;     % Valor padrão
            app.Property.rro = 0.816;     % Valor padrão
            app.Property.iro = 0.754;     % Valor padrão
            app.Property.tor = 11.9;      % Valor padrão
            app.Property.pol = 4;         % Valor padrão
            app.Motor = 1;
            app.Close = 0;
            app.Property.f = 0;
        end

        % Button pushed function: cancelar
        function cancelarButtonPushed(app, event)
            close(app.Programa);
        end

        % Value changed function: freque
        function frequeValueChanged(app, event)
            app.Property.fre = app.freque.Value;
            app.lamp3.Color = 'green';
        end

        % Value changed function: impest
        function impestValueChanged(app, event)
            app.Property.ies = app.impest.Value;
            app.lamp5.Color = 'green';
        end

        % Value changed function: impmut
        function impmutValueChanged(app, event)
            app.Property.imu = app.impmut.Value;
            app.lamp7.Color = 'green';
        end

        % Value changed function: improt
        function improtValueChanged(app, event)
            app.Property.iro = app.improt.Value;
            app.lamp10.Color = 'green';
        end

        % Value changed function: momine
        function momineValueChanged(app, event)
            app.Property.min = app.momine.Value;
            app.lamp8.Color = 'green';
        end

        % Value changed function: polos
        function polosValueChanged(app, event)
            app.Property.pol = app.polos.Value;
            app.lamp12.Color = 'green';
        end

        % Button pushed function: pronto
        function prontoButtonPushed(app, event)
            ok = questdlg('Deseja fazer mais alguma alteração?', 'Pronto', 'Sim','Não', 'Não');
            if strcmp(ok,'Não') == 1
                p1 = app.Property.ten;
                p2 = app.Property.fre;
                p3 = app.Property.res;
                p4 = app.Property.ies;
                p5 = app.Property.tor;
                p6 = app.Property.imu;
                p7 = app.Property.min;
                p8 = app.Property.rro;
                p9 = app.Property.iro;
                p10 = app.Property.pol;
                app.Retorno = [p1,p2,p3,p4,p5,p6,p7,p8,p9,p10];
                app.Property.f = 1;
                close(app.Programa);
            end
        end

        % Close request function: Programa
        function quandofechar(app, event)
            if app.Property.f == 0
                canc = questdlg('Tem certeza que deseja cancelar?', 'Cancelar', 'Sim','Não', 'Não');
                if strcmp(canc,'Sim') == 1
                    app.Retorno = 0;
                    app.Motor = 0;
                    app.Close = 1;
                    uiresume(app.parentFigureHandle);
                end
            else
                uiresume(app.parentFigureHandle);
            end
        end

        % Value changed function: resest
        function resestValueChanged(app, event)
            app.Property.res = app.resest.Value;
            app.lamp4.Color = 'green';
        end

        % Value changed function: resrot
        function resrotValueChanged(app, event)
            app.Property.rro = app.resrot.Value;
            app.lamp9.Color = 'green';
        end

        % Value changed function: tensao
        function tensaoValueChanged(app, event)
            app.Property.ten = app.tensao.Value;
            app.lamp1.Color = 'green';
        end

        % Value changed function: torque
        function torqueValueChanged(app, event)
            app.Property.tor = app.torque.Value;
            app.lamp11.Color = 'green';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Programa = uifigure;
            app.Programa.Name = 'SimulaMIT - Novo Motor';
            app.Programa.CloseRequestFcn = createCallbackFcn(app, @quandofechar, true);
            app.ResistnciadoEstatorEditFieldLabel = uilabel(app.Programa);
            app.ResistnciadoEstatorEditFieldLabel.Text = 'Resistência do Estator';
            app.resest = uieditfield(app.Programa, 'numeric');
            app.resest.ValueChangedFcn = createCallbackFcn(app, @resestValueChanged, true);
            app.resest.Limits = [0 Inf];
            app.resest.Value = 0.435;
            app.impest = uieditfield(app.Programa, 'numeric');
            app.impest.ValueChangedFcn = createCallbackFcn(app, @impestValueChanged, true);
            app.impest.Limits = [0 Inf];
            app.impest.Value = 0.754;
            app.texto1 = uilabel(app.Programa);
            app.texto1.Text = 'Indutância de Dispersão';
            app.texto2 = uilabel(app.Programa);
            app.texto2.Text = ' Do Estator';
            app.FrequnciaNominalEditFieldLabel = uilabel(app.Programa);
            app.FrequnciaNominalEditFieldLabel.Text = 'Frequência Nominal';
            app.freque = uieditfield(app.Programa, 'numeric');
            app.freque.ValueChangedFcn = createCallbackFcn(app, @frequeValueChanged, true);
            app.freque.Limits = [0 Inf];
            app.freque.Value = 60;
            app.TensoNominalEditFieldLabel = uilabel(app.Programa);
            app.TensoNominalEditFieldLabel.Text = 'Tensão Nominal';
            app.tensao = uieditfield(app.Programa, 'numeric');
            app.tensao.ValueChangedFcn = createCallbackFcn(app, @tensaoValueChanged, true);
            app.tensao.Limits = [0 Inf];
            app.tensao.Value = 220;
            app.improt = uieditfield(app.Programa, 'numeric');
            app.improt.ValueChangedFcn = createCallbackFcn(app, @improtValueChanged, true);
            app.improt.Limits = [0 Inf];
            app.improt.Value = 0.754;
            app.texto3 = uilabel(app.Programa);
            app.texto3.Text = ' Indutância de Dispersão';
            app.texto4 = uilabel(app.Programa);
            app.texto4.Text = ' Do Rotor';
            app.ResistnciadoRotorEditFieldLabel = uilabel(app.Programa);
            app.ResistnciadoRotorEditFieldLabel.Text = 'Resistência do Rotor';
            app.resrot = uieditfield(app.Programa, 'numeric');
            app.resrot.ValueChangedFcn = createCallbackFcn(app, @resrotValueChanged, true);
            app.resrot.Limits = [0 Inf];
            app.resrot.Value = 0.816;
            app.MomentoDeInrciaEditFieldLabel = uilabel(app.Programa);
            app.MomentoDeInrciaEditFieldLabel.Text = 'Momento De Inércia';
            app.momine = uieditfield(app.Programa, 'numeric');
            app.momine.ValueChangedFcn = createCallbackFcn(app, @momineValueChanged, true);
            app.momine.Limits = [0 Inf];
            app.momine.Value = 0.089;
            app.impmut = uieditfield(app.Programa, 'numeric');
            app.impmut.ValueChangedFcn = createCallbackFcn(app, @impmutValueChanged, true);
            app.impmut.Limits = [0 Inf];
            app.impmut.Value = 26.13;
            app.texto5 = uilabel(app.Programa);
            app.texto5.Text = ' Indutância Mútua Entre';
            app.texto6 = uilabel(app.Programa);
            app.texto6.Text = '   Rotor e Estator';
            app.VLabel = uilabel(app.Programa);
            app.VLabel.Text = 'V';
            app.HzLabel = uilabel(app.Programa);
            app.HzLabel.Text = 'Hz';
            app.OhmLabel1 = uilabel(app.Programa);
            app.OhmLabel1.Text = 'Ohm';
            app.OhmLabel2 = uilabel(app.Programa);
            app.OhmLabel2.Text = 'Ohm';
            app.OhmLabel3 = uilabel(app.Programa);
            app.OhmLabel3.Text = 'Ohm';
            app.KgmLabel = uilabel(app.Programa);
            app.KgmLabel.Text = 'Kg.m²';
            app.OhmLabel4 = uilabel(app.Programa);
            app.OhmLabel4.Text = 'Ohm';
            app.OhmLabel5 = uilabel(app.Programa);
            app.OhmLabel5.Text = 'Ohm';
            app.cancelar = uibutton(app.Programa, 'push');
            app.cancelar.ButtonPushedFcn = createCallbackFcn(app, @cancelarButtonPushed, true);
            app.cancelar.Text = 'Cancelar';
            app.pronto = uibutton(app.Programa, 'push');
            app.pronto.ButtonPushedFcn = createCallbackFcn(app, @prontoButtonPushed, true);
            app.pronto.Text = 'Pronto';
            app.lamp1 = uilamp(app.Programa);
            app.lamp1.Enable = 'off';
            app.lamp3 = uilamp(app.Programa);
            app.lamp3.Enable = 'off';
            app.lamp4 = uilamp(app.Programa);
            app.lamp4.Enable = 'off';
            app.lamp5 = uilamp(app.Programa);
            app.lamp5.Enable = 'off';
            app.lamp7 = uilamp(app.Programa);
            app.lamp7.Enable = 'off';
            app.lamp8 = uilamp(app.Programa);
            app.lamp8.Enable = 'off';
            app.lamp9 = uilamp(app.Programa);
            app.lamp9.Enable = 'off';
            app.lamp10 = uilamp(app.Programa);
            app.lamp10.Enable = 'off';
            app.NmLabel = uilabel(app.Programa);
            app.NmLabel.Text = 'N.m';
            app.TorqueNominalLabel = uilabel(app.Programa);
            app.TorqueNominalLabel.Text = 'Torque Nominal';
            app.torque = uieditfield(app.Programa, 'numeric');
            app.torque.ValueChangedFcn = createCallbackFcn(app, @torqueValueChanged, true);
            app.torque.Limits = [0 Inf];
            app.torque.Value = 11.9;
            app.NmeroDePolosLabel = uilabel(app.Programa);
            app.NmeroDePolosLabel.Text = 'Número De Polos';
            app.polos = uieditfield(app.Programa, 'numeric');
            app.polos.ValueChangedFcn = createCallbackFcn(app, @polosValueChanged, true);
            app.polos.Limits = [0 Inf];
            app.polos.Value = 4;
            app.lamp11 = uilamp(app.Programa);
            app.lamp11.Enable = 'off';
            app.lamp12 = uilamp(app.Programa);
            app.lamp12.Enable = 'off';
            app.TextArea = uitextarea(app.Programa);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'PARÂMETROS DO NOVO MOTOR'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = NOVOMOTOR

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Programa)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Programa)
        end
    end
end